classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        TabGroup               matlab.ui.container.TabGroup
        CodingTab              matlab.ui.container.Tab
        OpenAudioFileButton    matlab.ui.control.Button
        Switch                 matlab.ui.control.RockerSwitch
        SaveCodedFileButton    matlab.ui.control.Button
        Switch_2               matlab.ui.control.RockerSwitch
        DecodingTab            matlab.ui.container.Tab
        OpenCodedFileButton    matlab.ui.control.Button
        SaveDecodedFileButton  matlab.ui.control.Button
        Switch_3               matlab.ui.control.RockerSwitch
        Switch_4               matlab.ui.control.RockerSwitch
        mFilter                matlab.ui.control.UIAxes
        FinishButton           matlab.ui.control.Button
        ProccesingtakesalittletimePleaseWaittoseetheResultLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        path;
        filename ;
        audioSignal;
        Fs;
        player1;
        player2;
        plot_len ;
        changed_signal; % Description
        pathc ;
        filenamec ;
        codedSignal;
        Fsc;
        player3;
        player4;
        decoded;
        fzero;
        
    end
    
    methods (Access = private)
        
        function results = myfilter(app)
            Fs = 70;  % Sampling Frequency
            
            Fpass = 28;             % Passband Frequency
            Fstop = 28.5;            % Stopband Frequency
            Dpass = 0.057501127785;  % Passband Ripple
            Dstop = 1e-05;           % Stopband Attenuation
            dens  = 20;              % Density Factor
            
            % Calculate the order from the parameters using FIRPMORD.
            [N, Fo, Ao, W] = firpmord([Fpass, Fstop]/(Fs/2), [1 0], [Dpass, Dstop]);
            
            % Calculate the coefficients using the FIRPM function.
            b  = firpm(N, Fo, Ao, W, {dens});
            Hd = dfilt.dffir(b);
            [hf , wf] = freqz(Hd);
            
            plot(app.mFilter , wf/pi ,10*log10(abs(hf)) )
            hold( app.mFilter,'off');
            grid (app.mFilter , 'on');
            
            
        end
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            myfilter(app)
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: OpenAudioFileButton
        function OpenAudioFileButtonPushed(app, event)
            [app.filename,app.path] = uigetfile({'*.wav' ; '*.mp3'},'File Selector');
            
            if ~ isequal(app.filename,0)
                
                
                [Signal , app.Fs] = audioread(fullfile(app.path , app.filename));
                app.audioSignal = Signal(1:length(Signal));
                
                
                %-----Filter --------------
                
                
                Fs = 70;  % Sampling Frequency
                
                Fpass = 28;             % Passband Frequency
                Fstop = 28.5;            % Stopband Frequency
                Dpass = 0.057501127785;  % Passband Ripple
                Dstop = 1e-05;           % Stopband Attenuation
                dens  = 20;              % Density Factor
                
                % Calculate the order from the parameters using FIRPMORD.
                [N, Fo, Ao, W] = firpmord([Fpass, Fstop]/(Fs/2), [1 0], [Dpass, Dstop]);
                
                % Calculate the coefficients using the FIRPM function.
                b  = firpm(N, Fo, Ao, W, {dens});
                Hd = dfilt.dffir(b);
                
                %------End of Filter ------
                
                Fc = Fs * 1000;            % Sampling frequency
                T = 1/Fc;             % Sampling period
                L = length(app.audioSignal);             % Length of signal
                t = (0:L-1)*T;        % Time vector
                wf = (-L/2:L/2 - 1)*Fc/L;
                
                hsf  = fftshift(fft(app.audioSignal));
                plot(app.UIAxes  ,wf, 10*log10(abs(hsf)) )
                title(app.UIAxes , 'Orginal signal');
                hold( app.UIAxes,'off');
                grid (app.UIAxes , 'on');
                
                
                %--------------carrier----------
                fc = Fpass * 1000;
                app.fzero = fc;
                car = cos(2*pi*fc*t);
                
                %---------------
                %--------modulation -------
                app.changed_signal = filter(Hd,app.audioSignal);
                for k = 1 : length(app.changed_signal)
                    app.changed_signal(k) = app.changed_signal(k) * car(k);
                end
                %-------------
                
                app.changed_signal = filter(Hd,app.changed_signal);
                hcf  = fftshift(fft(app.changed_signal));
                
                plot(app.UIAxes2  ,wf,10*log10(abs(hcf)) )
                hold( app.UIAxes2,'off');
                grid (app.UIAxes2 , 'on');
                title(app.UIAxes2 , 'Coded signal');
                
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch value
                case 'Stop'
                    stop(app.player1)
                case 'Play'
                    
                    app.player1 = audioplayer(app.audioSignal , app.Fs);
                    play(app.player1);
            end
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            
            switch value
                case 'Stop'
                    stop(app.player2)
                case 'Play'
                    
                    app.player2 = audioplayer(app.changed_signal , app.Fs);
                    play(app.player2);
            end
        end

        % Button pushed function: SaveCodedFileButton
        function SaveCodedFileButtonPushed(app, event)
            
            name = app.filename;
            name = erase(name, '.wav');
            name = name + string('_coded.wav');
            audiowrite(name,app.changed_signal,app.Fs);
        end

        % Button pushed function: OpenCodedFileButton
        function OpenCodedFileButtonPushed(app, event)
            
            [app.filenamec,app.pathc] = uigetfile({'*.wav' ; '*.mp3'},'File Selector');
            if  isequal(app.filenamec,0)
                Signal = app.changed_signal ;
                app.Fsc = app.Fs;
            else
                
                [Signal , app.Fsc] = audioread(fullfile(app.pathc , app.filenamec));
            end
            app.codedSignal = Signal(1:length(Signal));
            %-----Filter 1--------------
            
            
            Fs = 70;  % Sampling Frequency
            
            Fpass = 28;             % Passband Frequency
            Fstop = 28.5;            % Stopband Frequency
            Dpass = 0.057501127785;  % Passband Ripple
            Dstop = 1e-05;           % Stopband Attenuation
            dens  = 20;              % Density Factor
            
            % Calculate the order from the parameters using FIRPMORD.
            [N, Fo, Ao, W] = firpmord([Fpass, Fstop]/(Fs/2), [1 0], [Dpass, Dstop]);
            
            % Calculate the coefficients using the FIRPM function.
            b  = firpm(N, Fo, Ao, W, {dens});
            Hd = dfilt.dffir(b);
            
            %------End of Filter ------
            
            
            Fc = Fs * 1000;            % Sampling frequency
            T = 1/Fc;             % Sampling period
            L = length(app.codedSignal);             % Length of signal
            t = (0:L-1)*T;        % Time vector
            wf = (-L/2:L/2 - 1)*Fc/L;
            
            hsf  = fftshift(fft(app.codedSignal));
            plot(app.UIAxes  ,wf, 10*log10(abs(hsf)) )
            title(app.UIAxes , 'input coded');
            hold( app.UIAxes,'off');
            grid (app.UIAxes , 'on');
            
            
            %--------------carrier----------
            fc = Fpass*1000;
            car = cos(2*pi*fc*t);
            
            %---------------
            %--------modulation -------
            app.decoded = filter(Hd,app.codedSignal);
            for k = 1 : length(app.decoded)
                app.decoded(k) = app.decoded(k) * car(k);
            end
            %-------------
            %-----Filter --------------
            
            
            Fs = 70;  % Sampling Frequency
            
            Fpass = 13.5;           % Passband Frequency
            Fstop = 13.75;              % Stopband Frequency
            Dpass = 0.057501127785;  % Passband Ripple
            Dstop = 1e-10;           % Stopband Attenuation
            dens  = 20;              % Density Factor
            
            % Calculate the order from the parameters using FIRPMORD.
            [N, Fo, Ao, W] = firpmord([Fpass, Fstop]/(Fs/2), [1 0], [Dpass, Dstop]);
            
            % Calculate the coefficients using the FIRPM function.
            b  = firpm(N, Fo, Ao, W, {dens});
            Hd2 = dfilt.dffir(b);
            
            %------End of Filter ------
            
            app.decoded = filter(Hd2,app.decoded);
            hcf  = fftshift(fft(app.decoded));
            
            plot(app.UIAxes2  ,wf,10*log10(abs(hcf)))
            hold( app.UIAxes2,'off');
            grid (app.UIAxes2 , 'on');
            title(app.UIAxes2 , 'DeCoded signal');
            
            
        end

        % Button pushed function: SaveDecodedFileButton
        function SaveDecodedFileButtonPushed(app, event)
            name = app.filenamec;
            name = erase(name, '.wav');
            name = name + string('_decoded.wav');
            audiowrite(name,app.decoded,app.Fsc);
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            value = app.Switch_3.Value;
            switch value
                case 'Stop'
                    stop(app.player3)
                case 'Play'
                    
                    app.player3 = audioplayer(app.codedSignal , app.Fsc);
                    play(app.player3);
                    
            end
        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            value = app.Switch_4.Value;
            switch value
                case 'Stop'
                    stop(app.player4)
                case 'Play'
                    
                    app.player4 = audioplayer(app.decoded , app.Fsc);
                    play(app.player4);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Signal')
            xlabel(app.UIAxes, 'Frequency (Hz)')
            ylabel(app.UIAxes, 'Magnitude (dB)')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Coded Signal')
            xlabel(app.UIAxes2, 'Frequency (Hz)')
            ylabel(app.UIAxes2, 'Magnitude (dB)')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CodingTab = uitab(app.TabGroup);
            app.CodingTab.Title = 'Coding';
            app.OpenAudioFileButton = uibutton(app.CodingTab, 'push');
            app.OpenAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenAudioFileButtonPushed, true);
            app.OpenAudioFileButton.Text = 'Open Audio File';
            app.Switch = uiswitch(app.CodingTab, 'rocker');
            app.Switch.Items = {'Stop', 'Play'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Stop';
            app.SaveCodedFileButton = uibutton(app.CodingTab, 'push');
            app.SaveCodedFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCodedFileButtonPushed, true);
            app.SaveCodedFileButton.Text = 'Save Coded File';
            app.Switch_2 = uiswitch(app.CodingTab, 'rocker');
            app.Switch_2.Items = {'Stop', 'Play'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Stop';
            app.DecodingTab = uitab(app.TabGroup);
            app.DecodingTab.Title = 'Decoding';
            app.OpenCodedFileButton = uibutton(app.DecodingTab, 'push');
            app.OpenCodedFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCodedFileButtonPushed, true);
            app.OpenCodedFileButton.Text = 'Open Coded File';
            app.SaveDecodedFileButton = uibutton(app.DecodingTab, 'push');
            app.SaveDecodedFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDecodedFileButtonPushed, true);
            app.SaveDecodedFileButton.Text = 'Save Decoded File';
            app.Switch_3 = uiswitch(app.DecodingTab, 'rocker');
            app.Switch_3.Items = {'Stop', 'Play'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = 'Stop';
            app.Switch_4 = uiswitch(app.DecodingTab, 'rocker');
            app.Switch_4.Items = {'Stop', 'Play'};
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.Switch_4.Value = 'Stop';
            app.mFilter = uiaxes(app.UIFigure);
            title(app.mFilter, 'Low Pass Filter')
            xlabel(app.mFilter, 'Normalized Frequency (\times\pi rad/samp)')
            ylabel(app.mFilter, 'Magnitude (dB)')
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.ProccesingtakesalittletimePleaseWaittoseetheResultLabel = uilabel(app.UIFigure);
            app.ProccesingtakesalittletimePleaseWaittoseetheResultLabel.Text = 'Proccesing takes a little time , Please Wait to see the Result ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end